classdef finalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
        CheckBox_3            matlab.ui.control.CheckBox
        Label_5               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        CheckBox_2            matlab.ui.control.CheckBox
        Label_2               matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        Panel                 matlab.ui.container.Panel
        Label                 matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        SavedataButton        matlab.ui.control.Button
        ButtonGroup           matlab.ui.container.ButtonGroup
        Button29              matlab.ui.control.RadioButton
        Button28              matlab.ui.control.RadioButton
        Button27              matlab.ui.control.RadioButton
        Button26              matlab.ui.control.RadioButton
        Button25              matlab.ui.control.RadioButton
        Button24              matlab.ui.control.RadioButton
        Button23              matlab.ui.control.RadioButton
        Button22              matlab.ui.control.RadioButton
        Button21              matlab.ui.control.RadioButton
        Button20              matlab.ui.control.RadioButton
        Button19              matlab.ui.control.RadioButton
        Button18              matlab.ui.control.RadioButton
        Button17              matlab.ui.control.RadioButton
        Button16              matlab.ui.control.RadioButton
        Button15              matlab.ui.control.RadioButton
        Button14              matlab.ui.control.RadioButton
        Button13              matlab.ui.control.RadioButton
        Button12              matlab.ui.control.RadioButton
        Button11              matlab.ui.control.RadioButton
        Button10              matlab.ui.control.RadioButton
        Button9               matlab.ui.control.RadioButton
        Button8               matlab.ui.control.RadioButton
        Button7               matlab.ui.control.RadioButton
        Button6               matlab.ui.control.RadioButton
        Button5               matlab.ui.control.RadioButton
        Button4               matlab.ui.control.RadioButton
        Button3               matlab.ui.control.RadioButton
        Button2               matlab.ui.control.RadioButton
        Button                matlab.ui.control.RadioButton
        SearchButton          matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        CheckBox              matlab.ui.control.CheckBox
        Tab_3                 matlab.ui.container.Tab
        Panel_5               matlab.ui.container.Panel
        periodButtonGroup     matlab.ui.container.ButtonGroup
        year1Button           matlab.ui.control.RadioButton
        halfYearButton        matlab.ui.control.RadioButton
        month3Button          matlab.ui.control.RadioButton
        month1Button          matlab.ui.control.RadioButton
        Label_23              matlab.ui.control.Label
        historyUIAxes         matlab.ui.control.UIAxes
        Panel_4               matlab.ui.container.Panel
        page2Search           matlab.ui.control.Button
        wavePercentEditField  matlab.ui.control.NumericEditField
        Label_22              matlab.ui.control.Label
        waveEditField         matlab.ui.control.NumericEditField
        Label_21              matlab.ui.control.Label
        yTotalEditField       matlab.ui.control.NumericEditField
        Label_17              matlab.ui.control.Label
        yDealEditField        matlab.ui.control.NumericEditField
        Label_15              matlab.ui.control.Label
        meanEditField         matlab.ui.control.NumericEditField
        Label_20              matlab.ui.control.Label
        lowEditField          matlab.ui.control.NumericEditField
        Label_19              matlab.ui.control.Label
        highEditField         matlab.ui.control.NumericEditField
        Label_18              matlab.ui.control.Label
        totalEditField        matlab.ui.control.NumericEditField
        Label_16              matlab.ui.control.Label
        dealEditField         matlab.ui.control.NumericEditField
        Label_14              matlab.ui.control.Label
        Label_13              matlab.ui.control.Label
        DateDropDown          matlab.ui.control.DropDown
        Label_12              matlab.ui.control.Label
        MonthDropDown         matlab.ui.control.DropDown
        Label_11              matlab.ui.control.Label
        YearDropDown          matlab.ui.control.DropDown
        Label_10              matlab.ui.control.Label
        timelyUIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %constant 
        fullStock; %contains stocks_type and link to each stocks in this type        
        currentTime = timer("Name","currentTimer"); % current time
        showTimePeriod = 1;%1s
        updateDataPeriod = 30;%30s   
        rawDataColName;
        fileTitle = "stocks";
        
        
        %variable
        autoUpdate = timer("Name","updateTimer");%auto update data
        rawData; % change by submit
        allDate; % change by every year
        isStart=true% Description
    end
    
    methods (Access = private)        
        function htmlResponse = crawlStockType(app,URL)              
            host = "www.cnyes.com";
            method = matlab.net.http.RequestMethod.GET;
            if strcmp(URL,"https://www.cnyes.com/twstock/stock_astock.aspx?ga=nav#")
                myURI = matlab.net.URI(URL);
            else
                myURI = matlab.net.URI("https://www.cnyes.com/twstock/"+URL);
            end
            header = matlab.net.http.HeaderField( ...
                "Host",host, ...
                "User-Agent","Mozilla/5.0 (X11; Ubuntu; Linux x86_64; rv:66.0) Gecko/20100101 Firefox/66.0",...
                "Accept", "application/json, text/javascript, */*; q=0.01",...
                "Accept-Language","zh-TW,zh;q=0.8,en-US;q=0.5,en;q=0.3",...
                "Accept-Encoding", "gzip, deflate, br",...
                "X-Requested-With","XMLHttpRequest",...
                "Connection","keep-alive");    
            request = matlab.net.http.RequestMessage(method,header);
            response=send(request,myURI);   
            htmlResponse=response;   
        end
        
        function dictionary = convert2dic(app,list)
            key = regexprep(string(regexpi(list,">.*<",'match')),"<*>*","");
            value=regexprep(string(regexpi(list,'"index.*"','match')),'"',"");    
            %dictionary = containers.Map(key,value);
            dictionary=containers.Map(key,value,'UniformValues',false);
        end
        
        function all = combineDic(app,d1,d2)
            temp=containers.Map;
            %single values
            singleKey=setxor(keys(d1),keys(d2));
            for key = singleKey
                key=string(key);
                if any(strcmp(keys(d1),key))
                    temp(key)=d1(key);
                else
                    temp(key)=d2(key);
                end
            end
            %multiple values
            multi = intersect(keys(d1),keys(d2));
            for key = multi
                key=string(key);
                temp(key)={d1(key),d2(key)};
            end
            all = temp;
        end
        function mytable = extractValue(app,list)
            temp = table;
            for i = 1:length(list)
                R=regexprep(regexp(list(i),'>.*?<',"match"),">*<*","");
                if(length(R)==25)
                    temp=[temp;cell2table(regexp(R([1,4,8,11,13,15,17,19,21,23,25])," ","split"))];
                elseif(length(R)==21)
                    temp=[temp;cell2table(regexp(R([1,3,5,7,9,11,13,15,17,19,21])," ","split"))];        
                else
                    "error in extra value"
                end
            end
            mytable = temp;
        end
        
        function results = getTable(app,key,isColName,type)
                response=crawlStockType(app,key{type});
                htmldata=response.Body.Data;
                list = extractBetween(htmldata, '<table cellspacing="0" rules="all" border="1">', "</table>");
                list = extractBetween(list,"<tr>","</tr>");
                if isColName
                    results=string(table2cell(extractValue(app,list(1))));
                else
                    results=extractValue(app,list(2:length(list)));
                end                
        end
        
        function changeUITableCol(app,uit)
            removeStyle(uit);
            low=uistyle; high=uistyle;
            high.FontColor = 'red';
            low.FontColor = 'green';     
            changeCol = 4;%成交
            compareCol = 8;%開盤
            testData = double(table2array(uit.Data));
            
            %red
            isHigh = testData(:,changeCol) >= testData(:,compareCol);            
            [row col]=ind2sub(size(uit.Data),find(isHigh));
            addStyle(uit,high,'cell',[row col+3]);
            
            %green
            isLow = testData(:,changeCol) < testData(:,compareCol);            
            [row col]=ind2sub(size(uit.Data),find(isLow));
            addStyle(uit,low,'cell',[row col+3]);
        end
        %timer  
        function myTimer(app,t,sec,doWhat)
            t.Period=sec;
            t.StartDelay=0;
            t.TasksToExecute=inf;
            t.ExecutionMode="fixedSpacing";   
            if strcmp(doWhat , "showTime")
                t.TimerFcn=@(~,~) showTime(app);
            else
                t.TimerFcn=@(~,~) updateData(app);
            end
            start(t)         
        end
        function showTime(app)  
            app.Label.Text = string(datestr(now,'HH:MM:SS'));
        end      
        function updateData(app)
            if ~isempty(app.UITable.Data)                 
                SearchButtonPushed(app,matlab.ui.eventdata.ButtonPushedData);
                %"auto update success"
            end
        end
        
        function RemoveNaObs(app)
            if ~isempty(app.UITable.Data)
                 app.UITable.Data=rmmissing((app.UITable.Data));
            end
        end
        
        function writeData(app)
            try
                temp=str2double(strjoin(string(clock),""));
                temp=string(round(temp));
                fName = app.fileTitle+app.ButtonGroup.SelectedObject.Text+"_"+temp;
                T = app.UITable.Data;
                T.Properties.VariableNames = app.rawDataColName;
                writetable(T,pwd+"\stocksData\"+fName+".xlsx");
            catch ME
                ME
                msgbox("error in write data");
                return;
            end  
            msgbox('Write Success','Success');
        end
        
        function showFigure(app)
            whatFig = app.YearDropDown.Value;%plot
            whatVar = app.DropDown_3.Value;        
            %ind = find(strcmp(app.rawDataColName,whatVar));
            %plotData=app.UITable.Data.(ind);
            T = app.UITable.Data;
            T.Properties.VariableNames = app.rawDataColName;               
            switch (whatFig)
                case "熱圖" 
                    try
                        heatmap(T,"ColorData",whatVar,"Colormap",redgreencmap(256));
                    catch ME
                        msgbox("Please install 'bioinformation' package.");                        
                        heatmap(T,"ColorData",whatVar);
                    end                    
            end            
            
        end
        
        %set all date in this year
        function allDateChange(app,year)
            t1 = datetime(str2num(year),1,1);
            t2 = t1 + calmonths(1:12);
            dt = string(caldiff(t2,'days'));
            dt = ["31d" dt];
            app.allDate = regexprep(dt,"d","");   
        end
        
        function result = crawl(app,url)
            html=string(webread(url));
            list = extractBetween(html, '<tbody>', '</tbody>');
            list = extractBetween(list, '<tr>', '</tr>');
            list = extractBetween(list, '<td>', '</td>');
            T=array2table(list);
            list = extractBetween(html, '<thead>', '</thead>');                
            list = extractBetween(list, '<td>', '</td>');
            T.Properties.VariableNames=list;
            result=T;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %start function
            %first page
            try
                response = crawlStockType(app,"https://www.cnyes.com/twstock/stock_astock.aspx?ga=nav#");
            catch ME
                ME
                msgbox("error in scraping");
                return;
            end            
            htmldata=response.Body.Data;
            list=extractBetween(htmldata, "<li>","</li>");
            %typeO=convert2dic(app,list(1:28));
            %typeT=convert2dic(app,list(29:52));
            typeO=convert2dic(app,list((end-24+1-28):(end-24+1)));
            typeT=convert2dic(app,list((end-24+1):end));
            
            app.fullStock=combineDic(app,typeO,typeT);            
            temp = keys(app.fullStock);
            for i = 1:length(app.ButtonGroup.Children)
                app.ButtonGroup.Children(i).Text = temp{i};
            end
            key=cellstr(app.fullStock("水泥"));
            app.UITable.ColumnName = getTable(app,key,true,1);
            app.rawDataColName = app.UITable.ColumnName;
            myTimer(app,app.currentTime,app.showTimePeriod,"showTime");  
            mkdir('stocksData');%for saving data 
            
            %second page
            temp = datetime("yesterday");
            temp = regexp(datestr(temp,'yyyy mm dd')," ",'split');            
            app.MonthDropDown.Value = app.MonthDropDown.Items(str2num(temp{2}));
            app.DateDropDown.Value = app.DateDropDown.Items(str2num(temp{3}));
            %set all date in this year
            allDateChange(app,temp{1}); 
            %set all date in this month
            app.DateDropDown.Items = string(1:str2num(app.allDate(str2num(temp{2})))); 
            %page2 search
            page2SearchButtonPushed(app,matlab.ui.eventdata.ButtonPushedData);
            app.isStart=false;
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            %submit function
            %get stock type in chinese
            stockType =  app.ButtonGroup.SelectedObject.Text;
            
            %get URL by stocktype and get data
            key=cellstr(app.fullStock(stockType));   
            tableData = table;
            for i = 1:size(key,2)      
                tableData = [tableData;getTable(app,key,false,i)];
            end            
            app.UITable.Data = tableData;             
            app.UITable.Data.(7)=strrep(app.UITable.Data.(7),'.',',');
            for i = [2,4:11]                
                app.UITable.Data.(i)  = str2double(app.UITable.Data.(i));
            end
            
            %sort
            if ~strcmp(app.DropDown.Value,"無")
                sortBy = find(app.UITable.ColumnName==string(app.DropDown.Value)); 
                if app.CheckBox_2.Value
                    app.UITable.Data=sortrows(app.UITable.Data,"Var"+string(sortBy),"descend");
                else
                    app.UITable.Data=sortrows(app.UITable.Data,"Var"+string(sortBy),"ascend");                
                end                
            end       
            app.rawData = app.UITable.Data; %remain raw data
            
            %check if remove nan
            if ~app.CheckBox_3.Value %remove na                
                RemoveNaObs(app);
            end
            %color change
            changeUITableCol(app,app.UITable); 
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.currentTime);
            stop(app.autoUpdate);
            delete(app)            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                myTimer(app,app.autoUpdate,app.updateDataPeriod,"updateData");
            else
                stop(app.autoUpdate)
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;%if remain nan company
            if value %remain
                if ~isempty(app.UITable.Data)
                    app.UITable.Data = app.rawData;
                    changeUITableCol(app,app.UITable); 
                end
            else %remove
                RemoveNaObs(app);       
                changeUITableCol(app,app.UITable); 
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %app.UITable.Data="";            
            app.UITable.Data=cell2table(cell(0,length(app.rawDataColName)), "VariableNames",app.rawDataColName);
            app.rawData="";
            app.ButtonGroup.SelectedObject = app.ButtonGroup.Children(end);            
            app.DropDown.Value=app.DropDown.Items{1};
            app.CheckBox.Value=false;%timer stop
            if isequal(get(app.autoUpdate,'Running') ,'on')
                 stop(app.autoUpdate);
            end
            app.CheckBox_2.Value=false;
            app.CheckBox_3.Value=false;
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            %save
            if isempty(app.UITable.Data)
                msgbox('ERROR : Data cannot be empty','Error','error'); 
                return
            else
                writeData(app);
            end
        end

        % Callback function
        function DropDown_3ValueChanged(app, event)
            if ~isempty(app.UITable.Data)
                showFigure(app);   
            end
        end

        % Callback function
        function DropDown_4ValueChanged(app, event)
            if ~isempty(app.UITable.Data)
                showFigure(app);   
            end
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            %year change
            value = app.YearDropDown.Value;
            allDateChange(app,value);
            value = app.MonthDropDown.Value;
            app.DateDropDown.Items = string(1:str2num(app.allDate(str2num(value)))); 
            
        end

        % Value changed function: MonthDropDown
        function MonthDropDownValueChanged(app, event)
            %month change
            value = app.MonthDropDown.Value;
            app.DateDropDown.Items = string(1:str2num(app.allDate(str2num(value)))); 
            
        end

        % Button pushed function: page2Search
        function page2SearchButtonPushed(app, event)
            %page2 search
            %timely axes
            year = string(str2num(app.YearDropDown.Value)+1911);
            month = str2num(app.MonthDropDown.Value);
            if month<10
                month = "0"+string(month);
            end
            date = str2num(app.DateDropDown.Value);
            if date<10
                date = "0"+string(date);
            end
            try
                %crawl detail
                url="https://www.twse.com.tw/exchangeReport/MI_5MINS_INDEX?response=html&date="+year+month+date;
                detailT = crawl(app,url);
                %crawl total
                url="https://www.twse.com.tw/exchangeReport/FMTQIK?response=html&date="+year+month+date;
                monthT = crawl(app,url);
                last = datetime(double(year),double(month),1)-calmonths(1);
                last = datestr(last,'yyyy mm dd');
                last = regexp(last," ",'split');
                lastUrl = "https://www.twse.com.tw/exchangeReport/FMTQIK?response=html&date="+last{1}+last{2}+"01";
                monthT = [crawl(app,lastUrl);monthT];                
            catch ME
                if ~app.isStart
                    msgbox("No data found or Internet Error : cannnot connect to www.twse.com.tw");
                end
                return;
            end
            
            %preprocessing
            format bank
            for i = 2:size(detailT,2)
                detailT.(i)=double(detailT.(i));
            end
            for i = 2:size(monthT,2)
                monthT.(i)=double(monthT.(i));
            end  
            
            %statistics
            app.highEditField.Value = max(detailT.(2));
            app.lowEditField.Value = min(detailT.(2));
            app.meanEditField.Value = mean(detailT.(2)); 
            thisDay = app.YearDropDown.Value+"/"+month+"/"+date;
            thisDayInd = find(monthT.(1)==thisDay);
            if isempty(thisDayInd)
                thisDayInd = size(monthT,1)+1;
            else
                app.totalEditField.Value = double(table2array(monthT(thisDayInd,3)))/100000000;
            end
            app.dealEditField.Value = table2array(detailT(size(detailT,1),2));
            app.yDealEditField.Value = double(table2array(monthT(thisDayInd-1,5)));            
            app.yTotalEditField.Value = double(table2array(monthT(thisDayInd-1,3)))/100000000;
            app.waveEditField.Value = table2array(detailT(size(detailT,1),2))-table2array(detailT(1,2));
            app.wavePercentEditField.Value = double((app.dealEditField.Value-app.yDealEditField.Value)/app.yDealEditField.Value);            
            
            %plot
            p=area(app.timelyUIAxes,1:size(detailT,1),detailT.(2));
            app.timelyUIAxes.XTick = [0:round(size(detailT,1)/4.5):size(detailT,1),size(detailT,1)];
            app.timelyUIAxes.XTickLabel =["9:00" "10:00" "11:00" "12:00" "1:00" "1:30"]; 
            alpha(p,.2);                      
            hold(app.timelyUIAxes,'on');
            
            temp=table2array(detailT);
            base=double(temp(1,2));
            plot(app.timelyUIAxes,[1,size(detailT,1)],[base,base],'LineWidth',2,'LineStyle',"--",'Color',[0 0 0]);              
            if app.meanEditField.Value<base
                lowerLine = app.meanEditField.Value;
                uperLine = base;
            else
                lowerLine = base;
                uperLine = app.meanEditField.Value;
            end
            app.timelyUIAxes.YLim =[lowerLine-3*std(detailT.(2)) uperLine+3*std(detailT.(2))];
            grid(app.timelyUIAxes, 'on');
            hold(app.timelyUIAxes,'off');  
            
            %changeColor
            if app.waveEditField.Value>0
                app.dealEditField.FontColor=[1,0,0];
                app.totalEditField.FontColor=[1,0,0];
                app.highEditField.FontColor=[1,0,0];
                app.lowEditField.FontColor=[1,0,0];
                app.meanEditField.FontColor=[1,0,0];
                app.waveEditField.FontColor=[1,0,0];
                app.wavePercentEditField.FontColor=[1,0,0];
            else
                app.dealEditField.FontColor=[0,1,0];
                app.totalEditField.FontColor=[0,1,0];
                app.highEditField.FontColor=[0,1,0];
                app.lowEditField.FontColor=[0,1,0];
                app.meanEditField.FontColor=[0,1,0];
                app.waveEditField.FontColor=[0,1,0];
                app.wavePercentEditField.FontColor=[0,1,0];
            end
            if table2array(monthT(thisDayInd-1,6))>0
                app.yDealEditField.FontColor = [1,0,0];
                app.yTotalEditField.FontColor=[1,0,0];
            else  
                app.yDealEditField.FontColor = [0,1,0];
                app.yTotalEditField.FontColor=[0,1,0];
            end
            
            
            %technology axes
            plotData = monthT;
            switch app.periodButtonGroup.SelectedObject.Text
                case '近1個月'
                    %not necessary crawl
                    crawlTimes=0;
                case '近3個月'
                    crawlTimes = 3-2; %2 = current data(monthT)
                case '近半年'
                    crawlTimes = 6-2;                    
                case '近一年'       
                    crawlTimes = 12-2;
            end
            
            last = datetime(double(year),double(month),1)-calmonths(1);
            if crawlTimes>=1
                 try
                    for i = 1:crawlTimes
                        last = last-calmonths(1);
                        temp = datestr(last,'yyyy mm dd');
                        temp = regexp(temp," ",'split');
                        lastUrl = "https://www.twse.com.tw/exchangeReport/FMTQIK?response=html&date="+temp{1}+temp{2}+"01";
                        plotData = [crawl(app,lastUrl);plotData];
                    end
                catch ME
                    return;
                end     
            end                  
            for i = 2:size(plotData,2)
                plotData.(i)=double(plotData.(i));
            end  
            plot(app.historyUIAxes,1:size(plotData,1),plotData.(5));
            %tick =  [1:round(size(plotData,1)/6):size(plotData,1),size(plotData,1)];
            tick =  [1:round(size(plotData,1)/6):size(plotData,1)];
            app.historyUIAxes.XTick = tick;
            app.historyUIAxes.XTickLabel = table2array(plotData(tick,1)); 
            grid(app.historyUIAxes,'minor');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '個股';
            app.CheckBox = uicheckbox(app.Tab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ResetButton = uibutton(app.Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = '重設';
            app.SearchButton = uibutton(app.Tab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = '搜尋';
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.Title = '公司類型';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.Button4 = uiradiobutton(app.ButtonGroup);
            app.Button4.Text = 'Button';
            app.Button5 = uiradiobutton(app.ButtonGroup);
            app.Button5.Text = 'Button';
            app.Button6 = uiradiobutton(app.ButtonGroup);
            app.Button6.Text = 'Button';
            app.Button7 = uiradiobutton(app.ButtonGroup);
            app.Button7.Text = 'Button2';
            app.Button8 = uiradiobutton(app.ButtonGroup);
            app.Button8.Text = 'Button3';
            app.Button9 = uiradiobutton(app.ButtonGroup);
            app.Button9.Text = 'Button';
            app.Button10 = uiradiobutton(app.ButtonGroup);
            app.Button10.Text = 'Button';
            app.Button11 = uiradiobutton(app.ButtonGroup);
            app.Button11.Text = 'Button';
            app.Button12 = uiradiobutton(app.ButtonGroup);
            app.Button12.Text = 'Button2';
            app.Button13 = uiradiobutton(app.ButtonGroup);
            app.Button13.Text = 'Button3';
            app.Button14 = uiradiobutton(app.ButtonGroup);
            app.Button14.Text = 'Button';
            app.Button15 = uiradiobutton(app.ButtonGroup);
            app.Button15.Text = 'Button';
            app.Button16 = uiradiobutton(app.ButtonGroup);
            app.Button16.Text = 'Button';
            app.Button17 = uiradiobutton(app.ButtonGroup);
            app.Button17.Text = 'Button2';
            app.Button18 = uiradiobutton(app.ButtonGroup);
            app.Button18.Text = 'Button3';
            app.Button19 = uiradiobutton(app.ButtonGroup);
            app.Button19.Text = 'Button';
            app.Button20 = uiradiobutton(app.ButtonGroup);
            app.Button20.Text = 'Button';
            app.Button21 = uiradiobutton(app.ButtonGroup);
            app.Button21.Text = 'Button';
            app.Button22 = uiradiobutton(app.ButtonGroup);
            app.Button22.Text = 'Button2';
            app.Button23 = uiradiobutton(app.ButtonGroup);
            app.Button23.Text = 'Button3';
            app.Button24 = uiradiobutton(app.ButtonGroup);
            app.Button24.Text = 'Button';
            app.Button25 = uiradiobutton(app.ButtonGroup);
            app.Button25.Text = 'Button';
            app.Button26 = uiradiobutton(app.ButtonGroup);
            app.Button26.Text = 'Button';
            app.Button27 = uiradiobutton(app.ButtonGroup);
            app.Button27.Text = 'Button';
            app.Button28 = uiradiobutton(app.ButtonGroup);
            app.Button28.Text = 'Button';
            app.Button29 = uiradiobutton(app.ButtonGroup);
            app.Button29.Text = 'Button';
            app.SavedataButton = uibutton(app.Tab, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = '儲存';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '30秒自動更新';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '當前時間';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '00:00:00';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'無', '代碼', '成交', '漲跌', '成交量', '開盤', '最高', '最低', '昨收', '漲%'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '無';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '排序';
            app.CheckBox_2 = uicheckbox(app.Tab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '遞減';
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '保留無交易之公司';
            app.CheckBox_3 = uicheckbox(app.Tab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '盤後分析';
            app.Panel_4 = uipanel(app.Tab_3);
            app.Panel_4.Title = '大盤走勢';
            app.timelyUIAxes = uiaxes(app.Panel_4);
            xlabel(app.timelyUIAxes, 'X')
            ylabel(app.timelyUIAxes, 'Y')
            zlabel(app.timelyUIAxes, 'Z')
            app.Label_10 = uilabel(app.Panel_4);
            app.Label_10.Text = '民國';
            app.YearDropDown = uidropdown(app.Panel_4);
            app.YearDropDown.Items = {'110', '109', '108', '107', '106', '105', '104', '103', '102', '101', '100', '99', '98', '97', '96', '95', '94', '93'};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = '110';
            app.Label_11 = uilabel(app.Panel_4);
            app.Label_11.Text = '年';
            app.MonthDropDown = uidropdown(app.Panel_4);
            app.MonthDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.MonthDropDown.ValueChangedFcn = createCallbackFcn(app, @MonthDropDownValueChanged, true);
            app.MonthDropDown.Value = '1';
            app.Label_12 = uilabel(app.Panel_4);
            app.Label_12.Text = '月';
            app.DateDropDown = uidropdown(app.Panel_4);
            app.DateDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31'};
            app.DateDropDown.Value = '1';
            app.Label_13 = uilabel(app.Panel_4);
            app.Label_13.Text = '日';
            app.Label_14 = uilabel(app.Panel_4);
            app.Label_14.Text = '成交';
            app.dealEditField = uieditfield(app.Panel_4, 'numeric');
            app.dealEditField.ValueDisplayFormat = '%.2f';
            app.dealEditField.Editable = 'off';
            app.Label_16 = uilabel(app.Panel_4);
            app.Label_16.Text = '金額(億)';
            app.totalEditField = uieditfield(app.Panel_4, 'numeric');
            app.totalEditField.ValueDisplayFormat = '%.2f';
            app.totalEditField.Editable = 'off';
            app.Label_18 = uilabel(app.Panel_4);
            app.Label_18.Text = '最高';
            app.highEditField = uieditfield(app.Panel_4, 'numeric');
            app.highEditField.ValueDisplayFormat = '%.2f';
            app.highEditField.Editable = 'off';
            app.Label_19 = uilabel(app.Panel_4);
            app.Label_19.Text = '最低';
            app.lowEditField = uieditfield(app.Panel_4, 'numeric');
            app.lowEditField.ValueDisplayFormat = '%.2f';
            app.lowEditField.Editable = 'off';
            app.Label_20 = uilabel(app.Panel_4);
            app.Label_20.Text = '均價';
            app.meanEditField = uieditfield(app.Panel_4, 'numeric');
            app.meanEditField.ValueDisplayFormat = '%.2f';
            app.meanEditField.Editable = 'off';
            app.Label_15 = uilabel(app.Panel_4);
            app.Label_15.Text = '昨收';
            app.yDealEditField = uieditfield(app.Panel_4, 'numeric');
            app.yDealEditField.ValueDisplayFormat = '%.2f';
            app.yDealEditField.Editable = 'off';
            app.Label_17 = uilabel(app.Panel_4);
            app.Label_17.Text = '昨量(億)';
            app.yTotalEditField = uieditfield(app.Panel_4, 'numeric');
            app.yTotalEditField.ValueDisplayFormat = '%.2f';
            app.yTotalEditField.Editable = 'off';
            app.Label_21 = uilabel(app.Panel_4);
            app.Label_21.Text = '漲跌';
            app.waveEditField = uieditfield(app.Panel_4, 'numeric');
            app.waveEditField.ValueDisplayFormat = '%.2f';
            app.waveEditField.Editable = 'off';
            app.Label_22 = uilabel(app.Panel_4);
            app.Label_22.Text = '漲幅';
            app.wavePercentEditField = uieditfield(app.Panel_4, 'numeric');
            app.wavePercentEditField.ValueDisplayFormat = '%.4f';
            app.wavePercentEditField.Editable = 'off';
            app.page2Search = uibutton(app.Panel_4, 'push');
            app.page2Search.ButtonPushedFcn = createCallbackFcn(app, @page2SearchButtonPushed, true);
            app.page2Search.Text = '查詢';
            app.Panel_5 = uipanel(app.Tab_3);
            app.Panel_5.Title = '歷史走勢';
            app.historyUIAxes = uiaxes(app.Panel_5);
            xlabel(app.historyUIAxes, 'X')
            ylabel(app.historyUIAxes, 'Y')
            zlabel(app.historyUIAxes, 'Z')
            app.Label_23 = uilabel(app.Panel_5);
            app.Label_23.Text = '期間';
            app.periodButtonGroup = uibuttongroup(app.Panel_5);
            app.month1Button = uiradiobutton(app.periodButtonGroup);
            app.month1Button.Text = '近1個月';
            app.month3Button = uiradiobutton(app.periodButtonGroup);
            app.month3Button.Text = '近3個月';
            app.halfYearButton = uiradiobutton(app.periodButtonGroup);
            app.halfYearButton.Text = '近半年';
            app.halfYearButton.Value = true;
            app.year1Button = uiradiobutton(app.periodButtonGroup);
            app.year1Button.Text = '近一年';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end